classdef my_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        PcoreEditField                matlab.ui.control.NumericEditField
        PcoreEditFieldLabel           matlab.ui.control.Label
        PrclEditField                 matlab.ui.control.NumericEditField
        PrclEditFieldLabel            matlab.ui.control.Label
        PconvEditField                matlab.ui.control.NumericEditField
        PconvEditFieldLabel           matlab.ui.control.Label
        StartingTorqueEditField       matlab.ui.control.NumericEditField
        StartingTorqueEditFieldLabel  matlab.ui.control.Label
        MaximumTorqueEditField        matlab.ui.control.NumericEditField
        MaximumTorqueEditFieldLabel   matlab.ui.control.Label
        PinEditField_3                matlab.ui.control.NumericEditField
        PinEditField_3Label           matlab.ui.control.Label
        PagEditField                  matlab.ui.control.NumericEditField
        PagEditFieldLabel             matlab.ui.control.Label
        PsclEditField                 matlab.ui.control.NumericEditField
        PsclEditFieldLabel            matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        R2sEditField                  matlab.ui.control.NumericEditField
        R2sEditFieldLabel             matlab.ui.control.Label
        jXmEditField                  matlab.ui.control.NumericEditField
        jXmEditFieldLabel             matlab.ui.control.Label
        RcEditField_3                 matlab.ui.control.NumericEditField
        RcEditField_3Label            matlab.ui.control.Label
        jX2EditField                  matlab.ui.control.NumericEditField
        jX2EditFieldLabel             matlab.ui.control.Label
        jX1EditField                  matlab.ui.control.NumericEditField
        jX1EditFieldLabel             matlab.ui.control.Label
        R1EditField_2                 matlab.ui.control.NumericEditField
        R1EditField_2Label            matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Tab2                          matlab.ui.container.Tab
        EditField                     matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
        Tab3                          matlab.ui.container.Tab
        EditField_2                   matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        Tab4                          matlab.ui.container.Tab
        EditField_3                   matlab.ui.control.EditField
        UIAxes3                       matlab.ui.control.UIAxes
        Tab5                          matlab.ui.container.Tab
        EditField_4                   matlab.ui.control.EditField
        UIAxes4                       matlab.ui.control.UIAxes
        Tab6                          matlab.ui.container.Tab
        EditField_5                   matlab.ui.control.EditField
        UIAxes5                       matlab.ui.control.UIAxes
        Tab7                          matlab.ui.container.Tab
        EditField_6                   matlab.ui.control.EditField
        UIAxes6                       matlab.ui.control.UIAxes
        Tab8                          matlab.ui.container.Tab
        EditField_7                   matlab.ui.control.EditField
        UIAxes7                       matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        SlipEditField                 matlab.ui.control.NumericEditField
        SlipEditFieldLabel            matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        ILEditField_3                 matlab.ui.control.NumericEditField
        ILEditField_3Label            matlab.ui.control.Label
        X2EditField                   matlab.ui.control.NumericEditField
        X2EditFieldLabel              matlab.ui.control.Label
        XmEditField                   matlab.ui.control.NumericEditField
        XmEditFieldLabel              matlab.ui.control.Label
        X1EditField                   matlab.ui.control.NumericEditField
        X1EditFieldLabel              matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        VLEditField_3                 matlab.ui.control.NumericEditField
        VLEditField_3Label            matlab.ui.control.Label
        R2EditField                   matlab.ui.control.NumericEditField
        R2EditFieldLabel              matlab.ui.control.Label
        RcEditField                   matlab.ui.control.NumericEditField
        RcEditFieldLabel              matlab.ui.control.Label
        R1EditField                   matlab.ui.control.NumericEditField
        R1EditFieldLabel              matlab.ui.control.Label
        NoofPolesEditField            matlab.ui.control.NumericEditField
        NoofPolesEditFieldLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        LockedRotorTestPanel          matlab.ui.container.Panel
        fEditField_2                  matlab.ui.control.NumericEditField
        fEditField_2Label             matlab.ui.control.Label
        PinEditField_2                matlab.ui.control.NumericEditField
        PinEditField_2Label           matlab.ui.control.Label
        ILEditField_2                 matlab.ui.control.NumericEditField
        ILEditField_2Label            matlab.ui.control.Label
        VLEditField_2                 matlab.ui.control.NumericEditField
        VLEditField_2Label            matlab.ui.control.Label
        SlipEditField_2               matlab.ui.control.NumericEditField
        SlipEditField_2Label          matlab.ui.control.Label
        RcEditField_2                 matlab.ui.control.NumericEditField
        RcEditField_2Label            matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        NoOfPolesEditField            matlab.ui.control.NumericEditField
        NoOfPolesEditFieldLabel       matlab.ui.control.Label
        DCTestPanel                   matlab.ui.container.Panel
        IDCEditField                  matlab.ui.control.NumericEditField
        IDCEditFieldLabel             matlab.ui.control.Label
        VDCEditField                  matlab.ui.control.NumericEditField
        VDCEditFieldLabel             matlab.ui.control.Label
        NoLoadTestPanel               matlab.ui.container.Panel
        fEditField                    matlab.ui.control.NumericEditField
        fEditFieldLabel               matlab.ui.control.Label
        PinEditField                  matlab.ui.control.NumericEditField
        PinEditFieldLabel             matlab.ui.control.Label
        ILEditField                   matlab.ui.control.NumericEditField
        ILEditFieldLabel              matlab.ui.control.Label
        VLEditField                   matlab.ui.control.NumericEditField
        VLEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            P = app.NoofPolesEditField.Value ;
            fse = app.FrequencyEditField.Value ;
            R1 = app.R1EditField.Value ;
            X1 = app.X1EditField.Value ;
            Rc = app.RcEditField.Value ;
            Xm = app.XmEditField.Value ;
            R2 = app.R2EditField.Value ;
            X2 = app.X2EditField.Value ;
            S = app.SlipEditField.Value ;
            VL = app.VLEditField_3.Value ;
            IL = app.ILEditField_3.Value ;
            
            s = 0 : 0.01 : 1 ;
            s(1) = 0.001 ;
            nsync = (120*fse) / P ;
            wsync = (2*pi*nsync) / 60 ;
            wm = (1 - s) * wsync ;
            nm = (1 - s) * nsync ;
            Vp = VL / (sqrt(3)) ;
            Vth = Vp * (Xm / sqrt(R1^2 + (X1+Xm)^2)) ;
            Zth = ((j*Xm) * (R1+j*X1)) / (R1 + j*(X1+Xm)) ;
            Rth = real(Zth) ;
            Xth = imag(Zth) ;
            Tmax = (3*Vth*Vth) / (2*wsync*(Rth + sqrt(Rth^2 + (Xth+X2)^2))) ;
            Tstart = (3*Vth*Vth*R2) / (wsync*((Rth+R2)^2 + (Xth+X2)^2)) ;
            PSCL = 3*IL*IL*R1 ;
            Y = 1 / (j*Xm) + 1/(R2/S + j*X2) + (1/Rc) ;
            Z = 1 / Y ;
            Iph = Vp / (R1 + j*X1 + Z) ;
            e1 = Vp - Iph*(R1+j*X1) ;
            PCORE = (abs(e1)^2) / Rc ;
            Pinput = sqrt(3) * VL * abs(Iph) ;
            PAG = Pinput - PCORE - PSCL ;
            T = PAG / wsync ;
            PCONV = (1-S) * PAG ;
            PRCL = S * PAG ;
            
            for ii = 1 : length(s)
                Tind(ii) = (3*Vth^2*R2/s(ii)) / (wsync*((Rth+R2/s(ii))^2 + (Xth+X2)^2)) ;
            end
            for ii = 1 : length(s)
                Yf(ii) = 1 / (j*Xm) + 1/(R2/s(ii) + j*X2) + (1/Rc) ;
                Zf(ii) = 1 / Yf(ii) ;
                Ip(ii) = Vp / (R1 + j*X1 + Zf(ii)) ;
                Pin(ii) = 3 * Vp * abs(Ip(ii)) * cos(angle(Ip(ii)));
            end

            for ii = 1 : length(s)
                Pconv(ii) = Tind(ii) * wm(ii) ;
            end
            for ii = 1 : length(s)
                eff(ii) = Pconv(ii) / Pin(ii) ;
            end

            for ii = 1 : length(s)
                E1(ii) = Vp - Ip(ii)*(R1+j*X1) ;
                Pcore(ii) = (3*(abs(E1(ii)))^2) / Rc ;
            end
            for ii = 1 : length(s)
                Pscl(ii) = 3 * (abs(Ip(ii)))^2 * R1 ;
            end
            for ii = 1 : length(s)
                Prcl(ii) = Pin(ii) - Pscl(ii) - Pcore(ii) ;
            end

            plot(app.UIAxes, nm, Tind) ;
            plot(app.UIAxes2, nm, Pin) ;
            plot(app.UIAxes3, nm, Pconv) ;
            plot(app.UIAxes4, nm, eff) ;
            plot(app.UIAxes5, nm, Pcore) ;
            plot(app.UIAxes6, nm, Pscl) ;
            plot(app.UIAxes7, nm, Prcl) ;
            
            app.R1EditField_2.Value = R1 ;
            app.jX1EditField.Value = X1 ;
            app.RcEditField_3.Value = Rc ;
            app.jXmEditField.Value = Xm ;
            app.R2sEditField.Value = R2/S ;
            app.jX2EditField.Value = X2 ;
            app.MaximumTorqueEditField.Value = Tmax ;
            app.StartingTorqueEditField.Value = Tstart ;
            app.PsclEditField.Value = PSCL ;
            app.PcoreEditField.Value = PCORE ;
            app.PinEditField_3.Value = Pinput ;
            app.PagEditField.Value = PAG ;
            app.PrclEditField.Value = PRCL ;
            app.PconvEditField.Value = abs(PCONV) ;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            p = app.NoOfPolesEditField.Value ;
            Vdc = app.VDCEditField.Value ;
            Idc = app.IDCEditField.Value ;
            Vnl = app.VLEditField.Value ;
            fnl = app.fEditField.Value ;
            IL_nl = app.ILEditField.Value ;
            Pinl = app.PinEditField.Value ;
            Vlr = app.VLEditField_2.Value ;
            flr = app.fEditField_2.Value ;
            IL_lr = app.ILEditField_2.Value ;
            Pilr = app.PinEditField_2.Value ;
            Rc = app.RcEditField_2.Value ;
            S = app.SlipEditField_2.Value ;
            VL = Vnl ;
            fse = fnl ;
            R1 = Vdc/(2*Idc) ;
            Znl = (Vnl/(sqrt(3)))/IL_nl ;
            Zlr = Vlr/(sqrt(3) * IL_lr) ;
            thetalr = acosd(Pilr/(sqrt(3) * Vlr * IL_lr)) ;
            Rlr = Zlr * cos(thetalr/(180/pi)) ;
            R2 = Rlr - R1 ;
            Xlrr = Zlr * sin(thetalr/(180/pi)) ;
            Xlr = (fnl/flr) * Xlrr ;
            X1 = Xlr/2 ;
            X2 = Xlr/2 ;
            Xm = Znl - X1 ;
            
            s = 0 : 0.01 : 1 ;
            s(1) = 0.001 ;
            nsync = (120*fse) / p ;
            wsync = (2*pi*nsync) / 60 ;
            wm = (1 - s) * wsync ;
            nm = (1 - s) * nsync ;
            Vp = VL / (sqrt(3)) ;
            Vth = Vp * (Xm / sqrt(R1^2 + (X1+Xm)^2)) ;
            Zth = ((j*Xm) * (R1+j*X1)) / (R1 + j*(X1+Xm)) ;
            Rth = real(Zth) ;
            Xth = imag(Zth) ;
            Tmax = (3*Vth*Vth) / (2*wsync*(Rth + sqrt(Rth^2 + (Xth+X2)^2))) ;
            Tstart = (3*Vth*Vth*R2) / (wsync*((Rth+R2)^2 + (Xth+X2)^2)) ;
            PSCL = 3*IL_nl*IL_nl*R1 ;
            Y = 1 / (j*Xm) + 1/(R2/S + j*X2) + (1/Rc) ;
            Z = 1 / Y ;
            Iph = Vp / (R1 + j*X1 + Z) ;
            e1 = Vp - Iph*(R1+j*X1) ;
            PCORE = (abs(e1)^2) / Rc ;
            Pinput = sqrt(3) * VL * abs(Iph) ;
            PAG = Pinput - PCORE - PSCL ;
            T = PAG / wsync ;
            PCONV = (1-S) * PAG ;
            PRCL = S * PAG ;

            for ii = 1 : length(s)
                Tind(ii) = (3*Vth^2*R2/s(ii)) / (wsync*((Rth+R2/s(ii))^2 + (Xth+X2)^2)) ;
            end
            for ii = 1 : length(s)
                Yf(ii) = 1 / (j*Xm) + 1/(R2/s(ii) + j*X2) + (1/Rc) ;
                Zf(ii) = 1 / Yf(ii) ;
                Ip(ii) = Vp / (R1 + j*X1 + Zf(ii)) ;
                Pin(ii) = 3 * Vp * abs(Ip(ii)) * cos(angle(Ip(ii)));
            end

            for ii = 1 : length(s)
                Pconv(ii) = Tind(ii) * wm(ii) ;
            end
            for ii = 1 : length(s)
                eff(ii) = Pconv(ii) / Pin(ii) ;
            end

            for ii = 1 : length(s)
                E1(ii) = Vp - Ip(ii)*(R1+j*X1) ;
                Pcore(ii) = (3*(abs(E1(ii)))^2) / Rc ;
            end
            for ii = 1 : length(s)
                Pscl(ii) = 3 * (abs(Ip(ii)))^2 * R1 ;
            end
            for ii = 1 : length(s)
                Prcl(ii) = Pin(ii) - Pscl(ii) - Pcore(ii) ;
            end

            plot(app.UIAxes, nm, Tind) ;
            plot(app.UIAxes2, nm, Pin) ;
            plot(app.UIAxes3, nm, Pconv) ;
            plot(app.UIAxes4, nm, eff) ;
            plot(app.UIAxes5, nm, Pcore) ;
            plot(app.UIAxes6, nm, Pscl) ;
            plot(app.UIAxes7, nm, Prcl) ;
            
            app.R1EditField_2.Value = R1 ;
            app.jX1EditField.Value = X1 ;
            app.RcEditField_3.Value = Rc ;
            app.jXmEditField.Value = Xm ;
            app.R2sEditField.Value = R2/S ;
            app.jX2EditField.Value = X2 ;
            app.MaximumTorqueEditField.Value = Tmax ;
            app.StartingTorqueEditField.Value = Tstart ;
            app.PsclEditField.Value = PSCL ;
            app.PcoreEditField.Value = PCORE ;
            app.PinEditField_3.Value = Pinput ;
            app.PagEditField.Value = PAG ;
            app.PrclEditField.Value = PRCL ;
            app.PconvEditField.Value = abs(PCONV) ;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PcoreEditField.Value = 0 ;
            app.PrclEditField.Value = 0 ;
            app.PconvEditField.Value = 0 ;
            app.StartingTorqueEditField.Value = 0 ;
            app.MaximumTorqueEditField.Value = 0 ;
            app.PinEditField_3.Value = 0 ;
            app.PagEditField.Value = 0 ;
            app.PsclEditField.Value = 0 ;
            app.R2sEditField.Value = 0 ;
            app.jXmEditField.Value = 0 ;
            app.RcEditField_3.Value = 0 ;
            app.jX2EditField.Value = 0 ;
            app.jX1EditField.Value = 0 ;
            app.R1EditField_2.Value = 0 ;
            app.SlipEditField.Value = 0 ;
            app.ILEditField_3.Value = 0 ;
            app.X2EditField.Value = 0 ;
            app.XmEditField.Value = 0 ;
            app.X1EditField.Value = 0 ;
            app.FrequencyEditField.Value = 0 ;
            app.VLEditField_3.Value = 0 ;
            app.R2EditField.Value = 0 ;
            app.RcEditField.Value = 0 ;
            app.R1EditField.Value = 0 ;
            app.NoofPolesEditField.Value = 0 ;
            app.fEditField_2.Value = 0 ;
            app.PinEditField_2.Value = 0 ;
            app.ILEditField_2.Value = 0 ;
            app.VLEditField_2.Value = 0 ;
            app.SlipEditField_2.Value = 0 ;
            app.RcEditField_2.Value = 0 ;
            app.NoOfPolesEditField.Value = 0 ;
            app.IDCEditField.Value = 0 ;
            app.VDCEditField.Value = 0 ;
            app.fEditField.Value = 0 ;
            app.PinEditField.Value = 0 ;
            app.ILEditField.Value = 0 ;
            app.VLEditField.Value = 0 ;

            xx = linspace(0, 1, 10) ;
            yy = 0 ;
            plot(app.UIAxes, xx, yy) ;
            plot(app.UIAxes2, xx, yy) ;
            plot(app.UIAxes3, xx, yy) ;
            plot(app.UIAxes4, xx, yy) ;
            plot(app.UIAxes5, xx, yy) ;
            plot(app.UIAxes6, xx, yy) ;
            plot(app.UIAxes7, xx, yy) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.NoLoadTestPanel = uipanel(app.Panel);
            app.NoLoadTestPanel.Title = 'No Load Test';
            app.VLEditFieldLabel = uilabel(app.NoLoadTestPanel);
            app.VLEditFieldLabel.Text = 'VL';
            app.VLEditField = uieditfield(app.NoLoadTestPanel, 'numeric');
            app.ILEditFieldLabel = uilabel(app.NoLoadTestPanel);
            app.ILEditFieldLabel.Text = 'IL';
            app.ILEditField = uieditfield(app.NoLoadTestPanel, 'numeric');
            app.PinEditFieldLabel = uilabel(app.NoLoadTestPanel);
            app.PinEditFieldLabel.Text = 'Pin';
            app.PinEditField = uieditfield(app.NoLoadTestPanel, 'numeric');
            app.fEditFieldLabel = uilabel(app.NoLoadTestPanel);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.NoLoadTestPanel, 'numeric');
            app.DCTestPanel = uipanel(app.Panel);
            app.DCTestPanel.Title = 'DC Test';
            app.VDCEditFieldLabel = uilabel(app.DCTestPanel);
            app.VDCEditFieldLabel.Text = 'VDC';
            app.VDCEditField = uieditfield(app.DCTestPanel, 'numeric');
            app.IDCEditFieldLabel = uilabel(app.DCTestPanel);
            app.IDCEditFieldLabel.Text = 'IDC';
            app.IDCEditField = uieditfield(app.DCTestPanel, 'numeric');
            app.NoOfPolesEditFieldLabel = uilabel(app.Panel);
            app.NoOfPolesEditFieldLabel.Text = 'No. Of Poles';
            app.NoOfPolesEditField = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RcEditField_2Label = uilabel(app.Panel);
            app.RcEditField_2Label.Text = 'Rc';
            app.RcEditField_2 = uieditfield(app.Panel, 'numeric');
            app.SlipEditField_2Label = uilabel(app.Panel);
            app.SlipEditField_2Label.Text = 'Slip';
            app.SlipEditField_2 = uieditfield(app.Panel, 'numeric');
            app.LockedRotorTestPanel = uipanel(app.Panel);
            app.LockedRotorTestPanel.Title = 'Locked Rotor Test';
            app.VLEditField_2Label = uilabel(app.LockedRotorTestPanel);
            app.VLEditField_2Label.Text = 'VL';
            app.VLEditField_2 = uieditfield(app.LockedRotorTestPanel, 'numeric');
            app.ILEditField_2Label = uilabel(app.LockedRotorTestPanel);
            app.ILEditField_2Label.Text = 'IL';
            app.ILEditField_2 = uieditfield(app.LockedRotorTestPanel, 'numeric');
            app.PinEditField_2Label = uilabel(app.LockedRotorTestPanel);
            app.PinEditField_2Label.Text = 'Pin';
            app.PinEditField_2 = uieditfield(app.LockedRotorTestPanel, 'numeric');
            app.fEditField_2Label = uilabel(app.LockedRotorTestPanel);
            app.fEditField_2Label.Text = 'f';
            app.fEditField_2 = uieditfield(app.LockedRotorTestPanel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.NoofPolesEditFieldLabel = uilabel(app.Panel_2);
            app.NoofPolesEditFieldLabel.Text = 'No. of Poles';
            app.NoofPolesEditField = uieditfield(app.Panel_2, 'numeric');
            app.NoofPolesEditField.Value = 4;
            app.R1EditFieldLabel = uilabel(app.Panel_2);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.Panel_2, 'numeric');
            app.R1EditField.Value = 0.641;
            app.RcEditFieldLabel = uilabel(app.Panel_2);
            app.RcEditFieldLabel.Text = 'Rc';
            app.RcEditField = uieditfield(app.Panel_2, 'numeric');
            app.RcEditField.Value = 10000000;
            app.R2EditFieldLabel = uilabel(app.Panel_2);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.Panel_2, 'numeric');
            app.R2EditField.Value = 0.3;
            app.VLEditField_3Label = uilabel(app.Panel_2);
            app.VLEditField_3Label.Text = 'VL';
            app.VLEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.VLEditField_3.Value = 460;
            app.FrequencyEditFieldLabel = uilabel(app.Panel_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel_2, 'numeric');
            app.FrequencyEditField.Value = 60;
            app.X1EditFieldLabel = uilabel(app.Panel_2);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Panel_2, 'numeric');
            app.X1EditField.Value = 0.75;
            app.XmEditFieldLabel = uilabel(app.Panel_2);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.Panel_2, 'numeric');
            app.XmEditField.Value = 26.3;
            app.X2EditFieldLabel = uilabel(app.Panel_2);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.Panel_2, 'numeric');
            app.X2EditField.Value = 0.5;
            app.ILEditField_3Label = uilabel(app.Panel_2);
            app.ILEditField_3Label.Text = 'IL';
            app.ILEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.CalculateButton_2 = uibutton(app.Panel_2, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.SlipEditFieldLabel = uilabel(app.Panel_2);
            app.SlipEditFieldLabel.Text = 'Slip';
            app.SlipEditField = uieditfield(app.Panel_2, 'numeric');
            app.SlipEditField.Value = 0.05;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'matlab.PNG';
            app.R1EditField_2Label = uilabel(app.Tab);
            app.R1EditField_2Label.Text = 'R1';
            app.R1EditField_2 = uieditfield(app.Tab, 'numeric');
            app.jX1EditFieldLabel = uilabel(app.Tab);
            app.jX1EditFieldLabel.Text = 'jX1';
            app.jX1EditField = uieditfield(app.Tab, 'numeric');
            app.jX2EditFieldLabel = uilabel(app.Tab);
            app.jX2EditFieldLabel.Text = 'jX2';
            app.jX2EditField = uieditfield(app.Tab, 'numeric');
            app.RcEditField_3Label = uilabel(app.Tab);
            app.RcEditField_3Label.Text = 'Rc ';
            app.RcEditField_3 = uieditfield(app.Tab, 'numeric');
            app.jXmEditFieldLabel = uilabel(app.Tab);
            app.jXmEditFieldLabel.Text = 'jXm';
            app.jXmEditField = uieditfield(app.Tab, 'numeric');
            app.R2sEditFieldLabel = uilabel(app.Tab);
            app.R2sEditFieldLabel.Text = 'R2/s ';
            app.R2sEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EditField = uieditfield(app.Tab2, 'text');
            app.EditField.Value = 'Torque-Speed Curve';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.EditField_2 = uieditfield(app.Tab3, 'text');
            app.EditField_2.Value = 'Input Power--Speed Curve';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes3 = uiaxes(app.Tab4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.EditField_3 = uieditfield(app.Tab4, 'text');
            app.EditField_3.Value = 'Converted Power--Speed Curve';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes4 = uiaxes(app.Tab5);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.EditField_4 = uieditfield(app.Tab5, 'text');
            app.EditField_4.Value = 'Efficiency--Speed Curve';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes5 = uiaxes(app.Tab6);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.EditField_5 = uieditfield(app.Tab6, 'text');
            app.EditField_5.Value = 'Core Loss--Speed Curve';
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.UIAxes6 = uiaxes(app.Tab7);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.EditField_6 = uieditfield(app.Tab7, 'text');
            app.EditField_6.Value = 'Stator Copper Loss--Speed Curve';
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = 'Tab8';
            app.UIAxes7 = uiaxes(app.Tab8);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.EditField_7 = uieditfield(app.Tab8, 'text');
            app.EditField_7.Value = 'Rotor Copper Loss--Speed Curve';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel_3 = uipanel(app.UIFigure);
            app.PsclEditFieldLabel = uilabel(app.Panel_3);
            app.PsclEditFieldLabel.Text = 'Pscl';
            app.PsclEditField = uieditfield(app.Panel_3, 'numeric');
            app.PagEditFieldLabel = uilabel(app.Panel_3);
            app.PagEditFieldLabel.Text = 'Pag';
            app.PagEditField = uieditfield(app.Panel_3, 'numeric');
            app.PinEditField_3Label = uilabel(app.Panel_3);
            app.PinEditField_3Label.Text = 'Pin';
            app.PinEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.MaximumTorqueEditFieldLabel = uilabel(app.Panel_3);
            app.MaximumTorqueEditFieldLabel.Text = 'Maximum Torque';
            app.MaximumTorqueEditField = uieditfield(app.Panel_3, 'numeric');
            app.StartingTorqueEditFieldLabel = uilabel(app.Panel_3);
            app.StartingTorqueEditFieldLabel.Text = 'Starting Torque';
            app.StartingTorqueEditField = uieditfield(app.Panel_3, 'numeric');
            app.PconvEditFieldLabel = uilabel(app.Panel_3);
            app.PconvEditFieldLabel.Text = 'Pconv';
            app.PconvEditField = uieditfield(app.Panel_3, 'numeric');
            app.PrclEditFieldLabel = uilabel(app.Panel_3);
            app.PrclEditFieldLabel.Text = 'Prcl';
            app.PrclEditField = uieditfield(app.Panel_3, 'numeric');
            app.PcoreEditFieldLabel = uilabel(app.Panel_3);
            app.PcoreEditFieldLabel.Text = 'Pcore';
            app.PcoreEditField = uieditfield(app.Panel_3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end